classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Graph1Panel       matlab.ui.container.Panel
        ztLabel           matlab.ui.control.Label
        z1                matlab.ui.control.EditField
        ytLabel           matlab.ui.control.Label
        y1                matlab.ui.control.EditField
        xtLabel           matlab.ui.control.Label
        x1                matlab.ui.control.EditField
        Graph2Panel       matlab.ui.container.Panel
        zsLabel_2         matlab.ui.control.Label
        z2                matlab.ui.control.EditField
        ysLabel           matlab.ui.control.Label
        y2                matlab.ui.control.EditField
        xsLabel           matlab.ui.control.Label
        x2                matlab.ui.control.EditField
        Button            matlab.ui.control.Button
        t1EditFieldLabel  matlab.ui.control.Label
        t1                matlab.ui.control.NumericEditField
        t2EditFieldLabel  matlab.ui.control.Label
        t2                matlab.ui.control.NumericEditField
        s1Label           matlab.ui.control.Label
        s1                matlab.ui.control.NumericEditField
        s2Label           matlab.ui.control.Label
        s2                matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        res               matlab.ui.control.Label
        tLabel            matlab.ui.control.Label
        ttt               matlab.ui.control.Label
        sLabel            matlab.ui.control.Label
        sss               matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            tt = app.t1.Value:0.1:app.t2.Value;
            ss = app.s1.Value:0.1:app.s2.Value;
            try
                x = double(subs(str2sym(app.x1.Value), 't', tt));
            catch
                x = tt;
                app.x1.Value = 't';
            end
            try
                y = double(subs(str2sym(app.y1.Value), 't', tt));
            catch
                y = tt;
                app.y1.Value = 't';
            end
            try
                z = double(subs(str2sym(app.z1.Value), 't', tt));
            catch
                z = tt;
                app.z1.Value = 't';
            end
            try
                plot3(app.UIAxes, x, y, z)
                hold(app.UIAxes, 'on')
            catch
                plot3(app.UIAxes, tt, tt, tt)
                hold(app.UIAxes, 'on')
            end
            
            try
                x = double(subs(str2sym(app.x2.Value), 's', ss));
            catch
                x = ss;
                app.x2.Value = 's';
            end
            try
                y = double(subs(str2sym(app.y2.Value), 's', ss));
            catch
                y = ss;
                app.y2.Value = 's';
            end
            try
                z = double(subs(str2sym(app.z2.Value), 's', ss));
            catch
                z = ss;
                app.z2.Value = 's';
            end
            try
                plot3(app.UIAxes, x, y, z)
                hold(app.UIAxes, 'off')
            catch
                plot3(app.UIAxes, ss, ss, ss)
                hold(app.UIAxes, 'off')
            end
                syms t s;
                xx1 = str2sym(app.x1.Value);
                yy1 = str2sym(app.y1.Value);
                zz1 = str2sym(app.z1.Value);
                xx2 = str2sym(app.x2.Value);
                yy2 = str2sym(app.y2.Value);
                zz2 = str2sym(app.z2.Value);
                f = @(m)(subs(xx1,m(1))-subs(xx2,m(2))).^2 + (subs(yy1,m(1))-subs(yy2,m(2))).^2 + (subs(zz1,m(1))-subs(zz2,m(2))).^2;
                
                x = fminsearch(f,[app.t1.Value+app.t2.Value, app.s1.Value+app.s2.Value]/2, optimset('TolX',1e-5));
                app.ttt.Text = string(x(1));
                app.sss.Text = string(x(2));
                z = sqrt(double(f(x)));
                app.res.Text = string(z);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph1Panel = uipanel(app.UIFigure);
            app.Graph1Panel.Title = 'Graph 1';
            app.ztLabel = uilabel(app.Graph1Panel);
            app.ztLabel.Text = 'z(t)=';
            app.z1 = uieditfield(app.Graph1Panel, 'text');
            app.z1.Tag = 'x1';
            app.z1.Value = 't+1';
            app.ytLabel = uilabel(app.Graph1Panel);
            app.ytLabel.Text = 'y(t)=';
            app.y1 = uieditfield(app.Graph1Panel, 'text');
            app.y1.Value = 't';
            app.xtLabel = uilabel(app.Graph1Panel);
            app.xtLabel.Text = 'x(t)=';
            app.x1 = uieditfield(app.Graph1Panel, 'text');
            app.x1.Value = 't';
            app.Graph2Panel = uipanel(app.UIFigure);
            app.Graph2Panel.Title = 'Graph 2';
            app.zsLabel_2 = uilabel(app.Graph2Panel);
            app.zsLabel_2.Text = 'z(s)=';
            app.z2 = uieditfield(app.Graph2Panel, 'text');
            app.z2.Value = 's';
            app.ysLabel = uilabel(app.Graph2Panel);
            app.ysLabel.Text = 'y(s)=';
            app.y2 = uieditfield(app.Graph2Panel, 'text');
            app.y2.Value = 's';
            app.xsLabel = uilabel(app.Graph2Panel);
            app.xsLabel.Text = 'x(s)=';
            app.x2 = uieditfield(app.Graph2Panel, 'text');
            app.x2.Value = 's';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.t1EditFieldLabel = uilabel(app.UIFigure);
            app.t1EditFieldLabel.Text = 't1=';
            app.t1 = uieditfield(app.UIFigure, 'numeric');
            app.t1.Limits = [-10 10];
            app.t1.Value = -2;
            app.t2EditFieldLabel = uilabel(app.UIFigure);
            app.t2EditFieldLabel.Text = 't2=';
            app.t2 = uieditfield(app.UIFigure, 'numeric');
            app.t2.Limits = [-10 10];
            app.t2.Value = 2;
            app.s1Label = uilabel(app.UIFigure);
            app.s1Label.Text = 's1=';
            app.s1 = uieditfield(app.UIFigure, 'numeric');
            app.s1.Limits = [-10 10];
            app.s1.Value = -2;
            app.s2Label = uilabel(app.UIFigure);
            app.s2Label.Text = 's2=';
            app.s2 = uieditfield(app.UIFigure, 'numeric');
            app.s2.Limits = [-10 10];
            app.s2.Value = 2;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Результат: ';
            app.res = uilabel(app.UIFigure);
            app.res.Text = '_';
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = {'t = '; ''};
            app.ttt = uilabel(app.UIFigure);
            app.ttt.Text = '_';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's = ';
            app.sss = uilabel(app.UIFigure);
            app.sss.Text = '_';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end