classdef tsk1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        Button            matlab.ui.control.Button
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        EditField9        matlab.ui.control.EditField
        EditField8        matlab.ui.control.EditField
        EditField7        matlab.ui.control.EditField
        EditField6        matlab.ui.control.EditField
        EditField_4       matlab.ui.control.NumericEditField
        EditField_3       matlab.ui.control.NumericEditField
        EditField_2       matlab.ui.control.NumericEditField
        EditField2        matlab.ui.control.EditField
        EditField         matlab.ui.control.EditField
        EditField_5       matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            s = app.EditField_2.Value + app.EditField_3.Value + app.EditField_4.Value;
            app.Label_6.Text = string(s);
            if s > app.EditField_5.Value
                app.Label_4.Text = "Вы прошли";
            else
                app.Label_4.Text = "Вы не прошли";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Результат';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Балл';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Предмет';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'ФИО';
            app.EditField9 = uieditfield(app.GridLayout, 'text');
            app.EditField8 = uieditfield(app.GridLayout, 'text');
            app.EditField7 = uieditfield(app.GridLayout, 'text');
            app.EditField6 = uieditfield(app.GridLayout, 'text');
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField2 = uieditfield(app.GridLayout, 'text');
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5Label = uilabel(app.GridLayout);
            app.EditField_5Label.Text = 'Порог';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = {'Сумма балов'; ''};
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '_';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tsk1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end